classdef object_counting_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        uploadfileButton      matlab.ui.control.Button
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_7              matlab.ui.control.UIAxes
        UIAxes_6              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        grayscaleSliderLabel  matlab.ui.control.Label
        grayscaleSlider       matlab.ui.control.Slider
        QuekYaoJingTeohKahLunB031810136B031810069Label  matlab.ui.control.Label
        createdbyLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        grayscale = 170
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: uploadfileButton
        function uploadfileButtonPushed(app, event)
           [filename, pathname] = uigetfile('*.png', 'Select an image?');
           if ~ischar(filename); return; end      %user cancelled
           filepath = fullfile(pathname, filename);
           image = imread(filepath);
           
           %image = imread(imgName);
           imshow(image,'Parent',app.UIAxes);

           
           %subplot(3,3,1), imshow(image), title('Original');
            
           % convert to grayscale
           grayscale=rgb2gray(image);
            
           % Find the histogram
           hist = imhist(grayscale);
           binaryImg = grayscale > app.grayscale;
            
           se=strel('disk', 4);
            
           % Use Image Opening Method
           afterOpening=imopen(binaryImg,se);
           %imshow(afterOpening,'After Opening',app.UIAxes1);
           imshow(afterOpening,'Parent',app.UIAxes_2);
           %subplot(3,3,2), imshow(afterOpening), title('After Opening');
           
           % Use Image Closing method
           afterClosing=imclose(afterOpening,se);
           imshow(afterClosing,'Parent',app.UIAxes_3);
           %subplot(3,3,3), imshow(afterClosing), title('After Closing');
            
           [ColorInverted,num] = bwlabel(~afterClosing,4);
           imshow(ColorInverted,'Parent',app.UIAxes_4);
           %subplot(3,3,4), imshow(ColorInverted), title('Inverted Colour');
            
           labeledImage = bwlabel(ColorInverted, 8);
           imshow(labeledImage,'Parent',app.UIAxes_5); 
           %subplot(3, 3, 5), imshow(labeledImage, []), title('Labeled Image, from bwlabel()');
           coloredLabels = label2rgb (labeledImage, 'hsv', 'k', 'shuffle'); % pseudo random color labels
           imshow(coloredLabels,'Parent',app.UIAxes_6); 
           %subplot(3, 3, 6), imshow(coloredLabels);
           %axis image;
           %title('Colour Label');
            
           blobMeasurements = regionprops(labeledImage, grayscale, 'all');
           numberOfBlobs = size(blobMeasurements, 1);
           imshow(grayscale,'Parent',app.UIAxes_7); 
           %subplot(3, 3, 7);
           %imshow(grayscale);
           %title('Segment out the boundaries, then find centroid. Then we can count the object in the image.'); 
           %axis image;
           hold(app.UIAxes_7, 'on');
           boundaries = bwboundaries(ColorInverted);
           numberOfBoundaries = size(boundaries, 1);
           for k = 1 : numberOfBoundaries
            thisBoundary = boundaries{k};
            	plot(app.UIAxes_7, thisBoundary(:,2), thisBoundary(:,1), 'g', 'LineWidth', 2);
            end
            hold(app.UIAxes_7, 'off');
            
            textFontSize = 14;	% Used to control size of "blob number" labels put atop the image.
            labelShiftX = -7;	
            blobECD = zeros(1, numberOfBlobs);
            fprintf(1,'Blob #      Mean Intensity  Area   Perimeter    Centroid       Diameter\n');
            for k = 1 : numberOfBlobs           % Loop through all blobs.
            	thisBlobsPixels = blobMeasurements(k).PixelIdxList;  % Get list of pixels in current blob.
            	meanGL = mean(grayscale(thisBlobsPixels)); % Find mean intensity (in original image!)
            	meanGL2008a = blobMeasurements(k).MeanIntensity;
            	
            	blobArea = blobMeasurements(k).Area;		% Get area.
            	blobPerimeter = blobMeasurements(k).Perimeter;		% Get perimeter.
            	blobCentroid = blobMeasurements(k).Centroid;		% Get centroid one at a time
            	blobECD(k) = sqrt(4 * blobArea / pi);					% Compute ECD - Equivalent Circular Diameter.
            	fprintf(1,'#%2d %17.1f %11.1f %8.1f %8.1f %8.1f % 8.1f\n', k, meanGL, blobArea, blobPerimeter, blobCentroid, blobECD(k));
                text(app.UIAxes_7, blobCentroid(1) + labelShiftX, blobCentroid(2), num2str(k), 'FontWeight', 'Bold', 'Color','red','FontSize', 24);
            end
           
           
        end

        % Callback function
        function testButtonPushed(app, event)


        end

        % Callback function
        function variablexDropDownValueChanged(app, event)
            
        end

        % Value changed function: grayscaleSlider
        function grayscaleSliderValueChanged(app, event)
            value = app.grayscaleSlider.Value;
            app.grayscale = value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.uploadfileButton = uibutton(app.UIFigure, 'push');
            app.uploadfileButton.ButtonPushedFcn = createCallbackFcn(app, @uploadfileButtonPushed, true);
            app.uploadfileButton.Text = 'upload file';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Labeled Image, from bwlabel()')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Segment out the boundaries, then find centroid. Then we can count the object in the image.')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'coloredLabels')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Inverted Colour')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'After Closing')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'After Opening')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Y')
            app.grayscaleSliderLabel = uilabel(app.UIFigure);
            app.grayscaleSliderLabel.Text = 'grayscale';
            app.grayscaleSlider = uislider(app.UIFigure);
            app.grayscaleSlider.Limits = [0 255];
            app.grayscaleSlider.ValueChangedFcn = createCallbackFcn(app, @grayscaleSliderValueChanged, true);
            app.grayscaleSlider.Value = 170;
            app.QuekYaoJingTeohKahLunB031810136B031810069Label = uilabel(app.UIFigure);
            app.QuekYaoJingTeohKahLunB031810136B031810069Label.Text = {'Quek Yao Jing &Teoh Kah Lun'; 'B031810136 & B031810069'};
            app.createdbyLabel = uilabel(app.UIFigure);
            app.createdbyLabel.Text = 'created by';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = object_counting_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end